classdef ecualizador < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        KHzLabel                    matlab.ui.control.Label
        KHzSlider                   matlab.ui.control.Slider
        KHzLabel_2                  matlab.ui.control.Label
        KHzSlider_2                 matlab.ui.control.Slider
        KHzLabel_3                  matlab.ui.control.Label
        KHzSlider_3                 matlab.ui.control.Slider
        SeleccionarArchivoButton    matlab.ui.control.Button
        GrabandoLampLabel           matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        TiempodeGrabacionEditFieldLabel  matlab.ui.control.Label
        TiempodeGrabacionEditField  matlab.ui.control.NumericEditField
        GrabarButton                matlab.ui.control.Button
        ArchivoLabel                matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        EcualizarButton             matlab.ui.control.Button
        TipodeventanaSwitchLabel    matlab.ui.control.Label
        TipodeventanaSwitch         matlab.ui.control.Switch
        ReproducirOriginalButton    matlab.ui.control.Button
        ReproducirModificadoButton  matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes4                     matlab.ui.control.UIAxes
        DetenerReproduccionButton   matlab.ui.control.Button
        GananciaLabel_2             matlab.ui.control.Label
        GananciaLabel_3             matlab.ui.control.Label
        GananciaLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        f
        Fxs % Description
        fs
        x
        y
        t
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SeleccionarArchivoButton
        function SeleccionarArchivoButtonPushed(app, event)
            [file, path] = uigetfile({'*.mp3';'*.ogg'},'File Selector');
            app.ArchivoLabel.Text = file;
            [app.x,app.fs] = audioread(file);
            n = length(app.x);
            app.t = (0:n-1)/app.fs;
            Fx = fft(app.x); 
            app.Fxs = fftshift(Fx); 
            app.f = (-n/2:n/2-1)*app.fs/n;
            plot(app.UIAxes,app.f,abs(app.Fxs));
            plot(app.UIAxes3, app.t, app.x);
            app.EcualizarButton.Enable = 'on'
            app.ReproducirOriginalButton.Enable = 'on'
            
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            app.Lamp.Color = 'green';
            [app.x, app.fs] = recordAudio(app.TiempodeGrabacionEditField.Value);
            app.Lamp.Color = 'red';
            n = length(app.x);
            app.t = (0:n-1)/app.fs;
            Fx = fft(app.x); 
            app.Fxs = fftshift(Fx); 
            app.f = (-n/2:n/2-1)*app.fs/n;
            plot(app.UIAxes,app.f,abs(app.Fxs));
            plot(app.UIAxes3, app.t, app.x);
            app.EcualizarButton.Enable = 'on';
            app.ReproducirOriginalButton.Enable = 'on'
        end

        % Button pushed function: EcualizarButton
        function EcualizarButtonPushed(app, event)
            if strcmpi(app.TipodeventanaSwitch.Value, 'Rectangular') == 1
                frec1 = app.KHzSlider.Value * rectangularWindow(app.f,app.Fxs,20,300);
                frec2 = app.KHzSlider_2.Value * rectangularWindow(app.f,app.Fxs,301,5000);
                frec3 = app.KHzSlider_3.Value * rectangularWindow(app.f,app.Fxs,5001,20000); 
                new_frec = frec1 + frec2 + frec3;
            else
                frec1 = app.KHzSlider.Value * blackmanWindow(app.f,app.Fxs,20,300);
                frec2 = app.KHzSlider_2.Value * blackmanWindow(app.f,app.Fxs,301,5000);
                frec3 = app.KHzSlider_3.Value * blackmanWindow(app.f,app.Fxs,5001,20000);
                
                new_frec = frec1 + frec2 + frec3;
                
                
            end
            plot(app.UIAxes2,app.f,abs(new_frec));
            app.ReproducirModificadoButton.Enable = 'on'
            Fx2 = ifftshift(new_frec); 
            app.y = real(ifft(Fx2)); 
            
            app.y = app.y./max(app.y);
            plot(app.UIAxes4, app.t, app.y);
        end

        % Button pushed function: ReproducirOriginalButton
        function ReproducirOriginalButtonPushed(app, event)
            clear sound;
            sound(app.x,app.fs);
        end

        % Button pushed function: ReproducirModificadoButton
        function ReproducirModificadoButtonPushed(app, event)
            clear sound;
            sound(app.y,app.fs);
        end

        % Button pushed function: DetenerReproduccionButton
        function DetenerReproduccionButtonPushed(app, event)
            clear sound;
        end

        % Value changing function: KHzSlider
        function KHzSliderValueChanging(app, event)
            changingValue = event.Value;
            app.GananciaLabel.Text = string(changingValue);
        end

        % Value changing function: KHzSlider_2
        function KHzSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.GananciaLabel_3.Text = string(changingValue);
        end

        % Value changing function: KHzSlider_3
        function KHzSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            app.GananciaLabel_2.Text = string(changingValue);          
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KHzLabel = uilabel(app.UIFigure);
            app.KHzLabel.Text = {'300 KHz'; ''};
            app.KHzSlider = uislider(app.UIFigure);
            app.KHzSlider.ValueChangingFcn = createCallbackFcn(app, @KHzSliderValueChanging, true);
            app.KHzSlider.Value = 1;
            app.KHzLabel_2 = uilabel(app.UIFigure);
            app.KHzLabel_2.Text = '5000 KHz';
            app.KHzSlider_2 = uislider(app.UIFigure);
            app.KHzSlider_2.ValueChangingFcn = createCallbackFcn(app, @KHzSlider_2ValueChanging, true);
            app.KHzSlider_2.Value = 1;
            app.KHzLabel_3 = uilabel(app.UIFigure);
            app.KHzLabel_3.Text = '20000 KHz';
            app.KHzSlider_3 = uislider(app.UIFigure);
            app.KHzSlider_3.ValueChangingFcn = createCallbackFcn(app, @KHzSlider_3ValueChanging, true);
            app.KHzSlider_3.Value = 1;
            app.SeleccionarArchivoButton = uibutton(app.UIFigure, 'push');
            app.SeleccionarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarArchivoButtonPushed, true);
            app.SeleccionarArchivoButton.Text = 'Seleccionar Archivo';
            app.GrabandoLampLabel = uilabel(app.UIFigure);
            app.GrabandoLampLabel.Text = {'Grabando'; ''};
            app.Lamp = uilamp(app.UIFigure);
            app.TiempodeGrabacionEditFieldLabel = uilabel(app.UIFigure);
            app.TiempodeGrabacionEditFieldLabel.Text = 'Tiempo de Grabacion';
            app.TiempodeGrabacionEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiempodeGrabacionEditField.Limits = [1 Inf];
            app.TiempodeGrabacionEditField.Value = 3;
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.ArchivoLabel = uilabel(app.UIFigure);
            app.ArchivoLabel.Text = 'Ningun archivo seleccionado';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Audio Original - Frecuencia')
            xlabel(app.UIAxes, 'Frecuencia')
            ylabel(app.UIAxes, {'Amplitud'; ''})
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Audio Modificado - Frecuencia')
            xlabel(app.UIAxes2, 'Frecuencia')
            ylabel(app.UIAxes2, 'Amplitud')
            app.EcualizarButton = uibutton(app.UIFigure, 'push');
            app.EcualizarButton.ButtonPushedFcn = createCallbackFcn(app, @EcualizarButtonPushed, true);
            app.EcualizarButton.Enable = 'off';
            app.EcualizarButton.Text = 'Ecualizar';
            app.TipodeventanaSwitchLabel = uilabel(app.UIFigure);
            app.TipodeventanaSwitchLabel.Text = 'Tipo de ventana';
            app.TipodeventanaSwitch = uiswitch(app.UIFigure, 'slider');
            app.TipodeventanaSwitch.Items = {'Rectangular', 'Blackman'};
            app.TipodeventanaSwitch.Value = 'Rectangular';
            app.ReproducirOriginalButton = uibutton(app.UIFigure, 'push');
            app.ReproducirOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirOriginalButtonPushed, true);
            app.ReproducirOriginalButton.Enable = 'off';
            app.ReproducirOriginalButton.Text = 'Reproducir Original';
            app.ReproducirModificadoButton = uibutton(app.UIFigure, 'push');
            app.ReproducirModificadoButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirModificadoButtonPushed, true);
            app.ReproducirModificadoButton.Enable = 'off';
            app.ReproducirModificadoButton.Text = 'Reproducir Modificado';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Audio Original - Tiempo')
            xlabel(app.UIAxes3, 'Tiempo')
            ylabel(app.UIAxes3, 'Amplitud')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Audio Modificado - Tiempo')
            xlabel(app.UIAxes4, 'Tiempo')
            ylabel(app.UIAxes4, 'Amplitud')
            app.DetenerReproduccionButton = uibutton(app.UIFigure, 'push');
            app.DetenerReproduccionButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerReproduccionButtonPushed, true);
            app.DetenerReproduccionButton.Text = 'Detener Reproduccion';
            app.GananciaLabel_2 = uilabel(app.UIFigure);
            app.GananciaLabel_2.Text = '1';
            app.GananciaLabel_3 = uilabel(app.UIFigure);
            app.GananciaLabel_3.Text = '1';
            app.GananciaLabel = uilabel(app.UIFigure);
            app.GananciaLabel.Text = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ecualizador

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end